classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        ConfigurationsButton  matlab.ui.control.Button
        DescriptionsLabel     matlab.ui.control.Label
        PickRoadButton        matlab.ui.control.Button
        Image                 matlab.ui.control.Image
        RightPanel            matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
        function roadMarkers = drawOneRoad(app)
            road_width = -1;
            number_of_ways = -1;
        
            disp("Draw in the map one road (Start by picking 1 starting point and the ending point of the road: )");
            while 1
                h = drawpolyline('Color','blue');
                
                while 1
                    road_width = str2double(input("\nEnter the road width in [m]: ", 's'));
                    if(road_width > 0 && not(isnan(road_width)))
                        break;
                    end
                end
                
                 while 1
                    number_of_ways = str2double(input("\nEnter the number of ways the road has [1 or 2 ways]: ", 's'));
                    if(number_of_ways > 0 && number_of_ways < 3  && not(isnan(number_of_ways)))
                        break;
                    end
                end
        
                %Because Matlab doesn't have a do...while I have to do this
                if ( strcmp(input("\nIf you're done drawing this road enter 'Y', if not press 'N': ", 's'), 'Y') == 1 )
                    break;
                end
            end
        
            % Change road color the mark it's done and retrieve pointer positions that
            % mark that roda
            h.Color = 'green';
            roadMarkers = h.Position;
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConfigurationsButton
        function ConfigurationsButtonPushed(app, event)
            app.DescriptionsLabel.Text = "Now on Configurating... Draw your roads";
        end

        % Button pushed function: PickRoadButton
        function PickRoadButtonPushed(app, event)
            app.ConfigurationsButton.Visible = false;
            roadMarkers = drawOneRoad(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ConfigurationsButton = uibutton(app.LeftPanel, 'push');
            app.ConfigurationsButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigurationsButtonPushed, true);
            app.ConfigurationsButton.Text = 'Configurations';
            app.DescriptionsLabel = uilabel(app.LeftPanel);
            app.DescriptionsLabel.Text = 'Descriptions:';
            app.PickRoadButton = uibutton(app.LeftPanel, 'push');
            app.PickRoadButton.ButtonPushedFcn = createCallbackFcn(app, @PickRoadButtonPushed, true);
            app.PickRoadButton.Text = 'Pick Road';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'map.PNG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end